classdef worldHandler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        PlayVoicedButton            matlab.ui.control.Button
        PlayUVButton                matlab.ui.control.Button
        ChangeaudiooutdeviceButton  matlab.ui.control.Button
        RESETButton                 matlab.ui.control.Button
        VLamp                       matlab.ui.control.Lamp
        VLampLabel                  matlab.ui.control.Label
        PLamp                       matlab.ui.control.Lamp
        PLampLabel                  matlab.ui.control.Label
        YLamp                       matlab.ui.control.Lamp
        YLampLabel                  matlab.ui.control.Label
        SLamp                       matlab.ui.control.Lamp
        SLampLabel                  matlab.ui.control.Label
        ALamp                       matlab.ui.control.Lamp
        ALampLabel                  matlab.ui.control.Label
        UNDOButton                  matlab.ui.control.Button
        SavesynthButton             matlab.ui.control.Button
        PlaysynthButton             matlab.ui.control.Button
        WorldHandlerLabel           matlab.ui.control.Label
        QUITButton                  matlab.ui.control.Button
        PlayoriginalButton          matlab.ui.control.Button
        SaveParameterButton         matlab.ui.control.Button
        SynthesisButton             matlab.ui.control.Button
        SpectrumButton              matlab.ui.control.Button
        AperiodicityButton          matlab.ui.control.Button
        FoextractorButton           matlab.ui.control.Button
        ReadsoundButton             matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        fileNameTextLabel           matlab.ui.control.Label
        fileFullPathTextLabel       matlab.ui.control.Label
        FileNameLabel               matlab.ui.control.Label
        fileFullPathLabel           matlab.ui.control.Label
        WaveAxesSynth               matlab.ui.control.UIAxes
        SgramAxes                   matlab.ui.control.UIAxes
        WaveAxes                    matlab.ui.control.UIAxes
        FoAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        CommonData % Globally accessible information
    end

    methods (Access = private)

        function results = freshStatusUpdate(app)
            switch app.CommonData.aperiodicity
                case 'on'
                    app.ALamp.Visible = 'off';
                case 'off'
                    app.ALamp.Visible = 'on';
            end
            switch app.CommonData.spectrum
                case 'on'
                    app.SLamp.Visible = 'off';
                case 'off'
                    app.SLamp.Visible = 'on';
            end
            switch app.CommonData.spectrum
                case 'on'
                    app.YLamp.Visible = 'off';
                case 'off'
                    app.YLamp.Visible = 'on';
            end
            switch app.CommonData.synthSound
                case 'on'
                    app.PLamp.Visible = 'off';
                    app.VLamp.Visible = 'off';
                case 'off'
                    app.PLamp.Visible = 'on';
                    app.VLamp.Visible = 'on';
            end
            %app.SLamp.Visible = app.SpectrumButton.Enable;
            %app.YLamp.Visible = app.SynthesisButton.Enable;
            %app.PLamp.Visible = app.PlaysynthButton.Enable;
            %app.VLamp.Visible = app.SavesynthButton.Enable;
            results = 'updated';
            %app.CommonData.aperiodicity = 'off';
                %app.CommonData.spectrum = 'off';
                %app.CommonData.synthSound = 'off';
        end

        function results = foLineButtonDnFcn(app,event)
            results = 1;
            FoAxesButtonDown(app, event);
            disp('line hit');
        end

        function results = setupWaveAxesView(app)
            results = 1;
            x = app.CommonData.x;
            fs = app.CommonData.samplingFrequency;
            cla(app.WaveAxes)
            tt = (0:length(x)-1)'/fs;
            app.CommonData.timeAxis = tt;
            app.CommonData.waveHandle = plot(app.WaveAxes, tt, x, ...
                "LineWidth", 2);
            app.WaveAxes.XLim = [tt(1) tt(end)];
            app.WaveAxes.YLim = max(abs(x)) * [-1 1.1];
            grid(app.WaveAxes, "on");
            xlabel(app.WaveAxes, '');
            title(app.WaveAxes, '');
            ylabel(app.WaveAxes, 'level');
            app.CommonData.waveAxisToolBar = ...
                axtoolbar(app.WaveAxes,{'pan','zoomin','zoomout','restoreview'});
            disableDefaultInteractivity(app.WaveAxes);
            switch app.CommonData.waveViewMode
                case 'initialize'
                    YlimWave = get(app.WaveAxes, 'YLim');
                    app.CommonData.dispVoiceLevel = YlimWave(2) * 0.95;
            end
            switch app.CommonData.waveViewMode
                case 'update'
                    hold(app.WaveAxes,"on")
                    %YlimWave = get(app.WaveAxes, 'YLim');
                    continuousFo = app.CommonData.f0;
                    tFrame = app.CommonData.tFrameFo;
                    vuv = app.CommonData.viewVUV;
                    app.CommonData.unVoicedHandle = [];
                    app.CommonData.unVoicedHandle = plot(app.WaveAxes, ...
                        tFrame, tFrame*0 + app.CommonData.dispVoiceLevel,"Color",[0 0 0], ...
                        "LineWidth", 2);
                    dispVUV = (4*vuv - 3) * app.CommonData.dispVoiceLevel;
                    dispVUV(dispVUV<0) = NaN;
                    %app.CommonData.dispVoiceLevel = YlimWave(2) * 0.95;
                    app.CommonData.voicedHandl = [];
                    app.CommonData.voicedHandle = plot(app.WaveAxes, ...
                        tFrame, dispVUV,"Color",[1 0 1], ...
                        "LineWidth", 3);
                    baseIdx = (1:length(tFrame))';
                    vuv = vuv(:); % make sure v-vec
                    onsetIdx = baseIdx(vuv > [0;vuv(1:end-1)]);
                    offsetIdx = baseIdx(vuv > [vuv(2:end);0]);
                    onsetLoc = tFrame(onsetIdx);
                    offsetLoc = tFrame(offsetIdx);
                    nSegment = length(onsetLoc);
                    app.CommonData.onsetdHandle = [];
                    app.CommonData.onsetdHandle = xline(app.WaveAxes, ...
                        onsetLoc(:), "Color",[0 1 0], "LineWidth", 0.5, HitTest="off");
                    app.CommonData.offsetdHandle = [];
                    app.CommonData.offsetdHandle = xline(app.WaveAxes, ...
                        offsetLoc(:), "Color",[1 0 0], "LineWidth", 0.5, HitTest="off");
                    %---- assign Segment data
                    segmentStr = struct;
                    segmentStr.onsetdHandle = app.CommonData.onsetdHandle;
                    segmentStr.offsetdHandle = app.CommonData.offsetdHandle;
                    set(app.WaveAxes, 'UserData', segmentStr);
                    %---- make continuous fo
                    continuousFo(1:onsetIdx(1)) = continuousFo(onsetIdx(1));
                    continuousFo(offsetIdx(end):end) = continuousFo(offsetIdx(end));
                    for ii = 2:nSegment
                        tmpIdx = offsetIdx(ii-1):onsetIdx(ii);
                        tmpFo = (tmpIdx - offsetIdx(ii-1))/(onsetIdx(ii) - offsetIdx(ii-1));
                        tmpFo = continuousFo(offsetIdx(ii-1)) ...
                            + (continuousFo(onsetIdx(ii)) ...
                            - continuousFo(offsetIdx(ii-1))) * tmpFo;
                        continuousFo(tmpIdx) = tmpFo;
                    end
                    app.CommonData.continuousFo = continuousFo;
                    app.CommonData.f0 = continuousFo;
                    hold(app.WaveAxes,"off")
            end
        end

        function results = updateVUV(app)
            results = 1;
            nSegment = length(app.CommonData.onsetdHandle);
            oldHandle = struct;
            oldHandle.onsetdHandle = app.CommonData.onsetdHandle;
            oldHandle.offsetdHandle = app.CommonData.offsetdHandle;
            %disp(oldHandle.onsetdHandle)
            %disp(oldHandle.offsetdHandle)
            newHandle = struct;
            indOk = 0;
            lastVoiceOff = 0;
            deleteList = zeros(nSegment,1);
            nDelete = 0;
            for ii = 1:nSegment
                xDataOn = get(app.CommonData.onsetdHandle(ii), 'Value');
                xDataOff = get(app.CommonData.offsetdHandle(ii), 'Value');
                if xDataOff(1,1) > xDataOn(1,1) && lastVoiceOff < xDataOn(1,1)
                    indOk = indOk + 1;
                    newHandle.onsetdHandle(ii) = oldHandle.onsetdHandle(ii);
                    newHandle.offsetdHandle(ii) = oldHandle.offsetdHandle(ii);
                    lastVoiceOff = xDataOff;
                else
                    %delete(oldHandle.onsetdHandle(ii));
                    %delete(oldHandle.offsetdHandle(ii));
                    nDelete = nDelete + 1;
                    deleteList(nDelete) = ii;
                    nextII = min(nSegment, ii + 1);
                    lastVoiceOff = get(app.CommonData.offsetdHandle(nextII), 'Value');
                end
            end
            if nDelete > 0
                for ii = nDelete:-1:1
                    deletId = deleteList(ii);
                    delete(oldHandle.onsetdHandle(deletId));
                    delete(oldHandle.offsetdHandle(max(1, deletId-1)));
                end
            end
            app.CommonData.onsetdHandle = newHandle.onsetdHandle;
            app.CommonData.offsetdHandle = newHandle.offsetdHandle;
            nSegment = length(app.CommonData.onsetdHandle);
            %disp('here')
            %disp(app.CommonData.onsetdHandle)
            tFrame = app.CommonData.tFrameFo;
            app.CommonData.viewVUV = app.CommonData.viewVUV * 0;
            app.CommonData.soundVUV = app.CommonData.soundVUV * 0;
            dataTime = (1:length(app.CommonData.soundVUV))'/app.CommonData.samplingFrequency;
            for ii = 1:nSegment
                xDataOn = get(app.CommonData.onsetdHandle(ii), 'Value');
                xDataOff = get(app.CommonData.offsetdHandle(ii), 'Value');
                app.CommonData.viewVUV(tFrame >= xDataOn(1,1) ...
                    & tFrame <= xDataOff(1,1)) = 1;
                app.CommonData.soundVUV(dataTime >= xDataOn(1,1) ...
                    & dataTime <= xDataOff(1,1)) = 1;
            end
            dispVUV = (4*app.CommonData.viewVUV - 3) * app.CommonData.dispVoiceLevel;
            dispVUV(dispVUV<0) = NaN;
            set(app.CommonData.voicedHandle, 'YData', dispVUV);
            %app.CommonData.waveViewMode = 'update';
            %setupWaveAxesView(app);
        end

        
        function results = updateOnOffHandle(app)
            results = 1;
            tmpHandle = get(app.WaveAxes, 'Children');
            nHandle = length(tmpHandle);
            newHandle = struct;
            nOnset = 0;
            nOffset = 0;
            onLocList = zeros(nHandle,1);
            offLocList = zeros(nHandle,1);
            for ii = 1:nHandle
                hType = get(tmpHandle(ii), 'Type');
                hColor = get(tmpHandle(ii), 'Color');
                hiD = hColor(1)*4+hColor(2)*2 + hColor(3);
                switch hType
                    case 'constantline'
                        location = get(tmpHandle(ii), 'Value');
                        switch hiD
                            case 4
                                nOffset = nOffset + 1;
                                newHandle.offsetdHandle(nOffset) = tmpHandle(ii);
                                offLocList(nOffset) = location;
                            case 2
                                nOnset = nOnset + 1;
                                newHandle.onsetdHandle(nOnset) = tmpHandle(ii);
                                onLocList(nOnset) = location;
                        end
                end
            end
            %disp([nOnset nOffset])
            [~, sortOnIdx] = sort(onLocList(1:nOnset));
            [~, sortOffIdx] = sort(offLocList(1:nOffset));
            app.CommonData.onsetdHandle = newHandle.onsetdHandle(sortOnIdx);
            app.CommonData.offsetdHandle = newHandle.offsetdHandle(sortOffIdx);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %screenSize = groot.ScreenSize;
            rootInfo = groot;
            screenSize = get(rootInfo, "ScreenSize");
            width = min(900, screenSize(3) - 21);
            hight = min(900, screenSize(4) - 21 - 30);
            set(app.UIFigure, "Position", [20 20 width hight]);
            app.CommonData.foEdit = 'off';
            fs = 44100;
            tt = (1:fs/2)'/fs;
            x = 0.2*sin(2*pi*tt*440);
            app.CommonData.deviceID = -1;
            app.CommonData.x = x;
            app.CommonData.samplingFrequency = fs;
            app.CommonData.player = audioplayer(x, fs, 24, app.CommonData.deviceID);
            play(app.CommonData.player)
            app.CommonData.aperiodicity = 'off';
            app.CommonData.spectrum = 'off';
            app.CommonData.synthSound = 'off';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {901, 901};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {247, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: ReadsoundButton
        function ReadsoundButtonPushed(app, event)
            [filename, fileFullPath] = uigetfile({'*.wav';'*.WAV';'*.*'});
            if isequal(filename, 0)
                disp('file selection cancelled.');
            else
                [x, fs] = audioread([fileFullPath filename]);
                [~, nChannel] = size(x);
                if nChannel > 1
                    channels = struct;
                    for ii = 1:nChannel
                        channels(ii).name = ['channel' num2str(ii)];
                    end
                    Tchannel = struct2table(channels);
                    [id, tf] = listdlg("ListString", Tchannel.name, "SelectionMode","single", ...
                        "PromptString","Select channel","ListSize", [160, 100]);
                    if tf
                        x = x(:, id);
                    else
                        x = x(:, 1); % select default
                    end
                end
                cla(app.WaveAxes);
                app.CommonData.x = x;
                app.CommonData.soundVUV = x(:,1)*0 + 1;
                app.CommonData.samplingFrequency = fs;
                app.fileFullPathTextLabel.Text = fileFullPath;
                app.fileFullPathTextLabel.Visible = 'on';
                app.fileNameTextLabel.Text = filename;
                app.fileNameTextLabel.Visible = 'on';
                %---- waveform plot
                app.WaveAxes.Visible = 'on';
                app.CommonData.waveViewMode = 'initialize';
                setupWaveAxesView(app);
                app.PlayoriginalButton.Enable = 'on';
                app.FoextractorButton.Enable = 'on';
                app.AperiodicityButton.Enable = 'off';
                app.SpectrumButton.Enable = 'off';
                app.PlaysynthButton.Enable = 'off';
                app.SynthesisButton.Enable = 'off';
                app.FoAxes.Visible = 'off';
                app.WaveAxesSynth.Visible = 'off';
                app.SgramAxes.Visible = 'off';
                app.CommonData.aperiodicity = 'off';
                app.CommonData.spectrum = 'off';
                app.CommonData.synthSound = 'off';
                cla(app.FoAxes);
                cla(app.WaveAxesSynth);
                cla(app.SgramAxes);
            end
        end

        % Button pushed function: QUITButton
        function QUITButtonPushed(app, event)
            close(app.UIFigure);
        end

        % Button pushed function: PlayoriginalButton
        function PlayoriginalButtonPushed(app, event)
            xLim = app.WaveAxes.XLim;
            fs = app.CommonData.samplingFrequency;
            x = app.CommonData.x;
            nData = length(x);
            xLimSample = max(1, min(nData, 1+round(xLim * fs)));
            span = xLimSample(1):xLimSample(end);
            app.CommonData.audioPlayer = ...
                audioplayer(x(span)/max(abs(x(span))), fs, 24,...
                app.CommonData.deviceID);
            play(app.CommonData.audioPlayer);
        end

        % Button pushed function: FoextractorButton
        function FoextractorButtonPushed(app, event)
            app.SpectrumButton.Enable = 'off';
            app.PlaysynthButton.Enable = 'off';
            app.SynthesisButton.Enable = 'off';
            app.FoAxes.Visible = 'off';
            app.WaveAxesSynth.Visible = 'off';
            app.SgramAxes.Visible = 'off';
            app.CommonData.separationMargin = 0.04;
            cla(app.FoAxes)
            cla(app.WaveAxesSynth)
            cla(app.SgramAxes)
            xLim = app.WaveAxes.XLim;
            fs = app.CommonData.samplingFrequency;
            x = app.CommonData.x;
            nData = length(x);
            xLimSample = max(1, min(nData, 1+round(xLim * fs)));
            span = xLimSample(1):xLimSample(end);
            app.CommonData.span = span;
            option_harvest.f0_floor = 40;
            app.CommonData.option_harvest = option_harvest;
            f0_parameter = Harvest(x(span), fs, option_harvest);
            app.CommonData.f0_parameter = f0_parameter;
            app.CommonData.f0_original = f0_parameter.f0;
            app.FoAxes.Visible = 'on';
            app.FoAxes.LineWidth = 2;
            [~, nTime] = size(f0_parameter.f0_candidates);
            ttCandidate = (0:nTime-1)'/1000 + xLim(1);
            tFrame = f0_parameter.temporal_positions + xLim(1);
            app.CommonData.candidateHandle = ...
                plot(app.FoAxes, ttCandidate, log(f0_parameter.f0_candidates'), '.');
            set(app.CommonData.candidateHandle, 'HitTest', 'off');
            grid(app.FoAxes, "on");
            hold(app.FoAxes, "on");
            yTickLogFreq = log([10 20 30 40 50 60 70 80 90 ...
                100 200 300 400 500 600 700 800 900 1000 1500 2000]);
            yTiickLabel = {'.01', '.02', '.03', ' ', '.05', ' ', '.07', ' ', ' ', ...
                '.1', '.2', '.3', ' ', '.5', ' ', ' .7', ' ', ' ', ...
                '1', ' ', '2'};
            set(app.FoAxes, 'YTick', yTickLogFreq, 'YTickLabel', yTiickLabel);
            app.CommonData.f0 = f0_parameter.f0;
            app.CommonData.tFrameFo = tFrame;
            app.CommonData.originalf0Handle = ...
                plot(app.FoAxes, tFrame, log(f0_parameter.f0), ...
                'c', "LineWidth", 5);
            set(app.CommonData.originalf0Handle, 'ButtonDownFcn', @(app,event)foLineButtonDnFcn(app,event), ...
                'UserData', app, 'HitTest', 'off');
            f0_voiced = f0_parameter.f0;
            vuv = f0_parameter.vuv;
            app.CommonData.viewVUV = vuv;
            f0_voiced(vuv < 0.5) = NaN;
            app.CommonData.f0Handle = ...
                plot(app.FoAxes, tFrame, log(f0_voiced), ...
                'k', "LineWidth", 2);
            set(app.CommonData.f0Handle, 'HitTest', 'off');
            %app.CommonData.f_0floorHandle = ...
            %    yline(app.FoAxes, log(option_harvest.f0_floor),...
            %    '-', "LineWidth", 2, "Color", [1 0 0]);
            %set(app.CommonData.f0_floorHandle, 'ButtonDownFcn', @(app, event)FoAxesButtonDown(app.FoAxes, event));
            hold(app.FoAxes, "off")
            axis(app.FoAxes, [xLim(1) xLim(end) ...
                log(option_harvest.f0_floor) log(1500)]);
            xlabel(app.FoAxes, '');
            ylabel(app.FoAxes, 'frequency (Hz)')
            title(app.FoAxes, '');
            %---- add VUV information
            %app.WaveAxes.Visible = 'on';
            app.CommonData.waveViewMode = 'update';
            setupWaveAxesView(app);
            set(app.CommonData.originalf0Handle, 'YData', ...
                log(app.CommonData.continuousFo));
            updateVUV(app);
            %----
            app.CommonData.FoAxesToolBar = ...
                axtoolbar(app.FoAxes,{'pan','zoomin','zoomout','restoreview'});
            disableDefaultInteractivity(app.FoAxes);
            drawnow;
            freshStatusUpdate(app);
            app.AperiodicityButton.Enable = 'on';
            linkaxes([app.FoAxes, app.WaveAxes], 'x');
            app.CommonData.foEdit = 'off';
            app.CommonData.foEditCount = 0;
            app.CommonData.foEditData = struct;
            app.PlayVoicedButton.Enable = 'on';
            app.PlayUVButton.Enable = 'on';
        end

        % Button pushed function: AperiodicityButton
        function AperiodicityButtonPushed(app, event)
            fs = app.CommonData.samplingFrequency;
            x = app.CommonData.x;
            span = app.CommonData.span;
            f0_parameter = app.CommonData.f0_parameter;
            f0_parameter.f0 = app.CommonData.f0(:)' .* app.CommonData.viewVUV(:)';
            option_harvest = app.CommonData.option_harvest;
            f0_parameter.vuv = ...
                double(f0_parameter.f0 > option_harvest.f0_floor);
            app.CommonData.f0_parameter.f0 = f0_parameter.f0;
            app.CommonData.f0_parameter.vuv = f0_parameter.vuv;
            option_cheaptrick.fft_size = 4096;
            option_d4c.fft_size = option_cheaptrick.fft_size;
            source_parameter = D4C(x(span), fs, f0_parameter, option_d4c);
            app.CommonData.source_parameter = source_parameter;
            app.SpectrumButton.Enable = 'on';
            app.ALamp.Visible = 'off';
            app.CommonData.aperiodicity = 'on';
            app.CommonData.spectrum = 'off';
            app.CommonData.synthSound = 'off';
        end

        % Button pushed function: SynthesisButton
        function SynthesisButtonPushed(app, event)
            span = app.CommonData.span;
            fs = app.CommonData.samplingFrequency;
            spectrum_parameter = app.CommonData.spectrum_parameter;
            source_parameter = app.CommonData.source_parameter;
            y = Synthesis(source_parameter, spectrum_parameter);
            app.CommonData.y = y;
            app.CommonData.tty = (0:length(y)-1)'/fs + (span(1) -1) /fs;
            app.WaveAxesSynth.Visible = 'on';
            app.CommonData.waveSynthHandle = plot(app.WaveAxesSynth, ...
                app.CommonData.tty, y, "LineWidth", 2);
            grid(app.WaveAxesSynth, "on");
            xlabel(app.WaveAxesSynth, '');
            ylabel(app.WaveAxesSynth, 'level');
            title(app.WaveAxesSynth, '');
            xLim = app.WaveAxes.XLim;
            app.CommonData.WaveAxesSynthToolBar = ...
                axtoolbar(app.WaveAxesSynth,{'pan','zoomin','zoomout','restoreview'});
            app.WaveAxesSynth.XLim = [xLim(1) xLim(2)];
            disableDefaultInteractivity(app.WaveAxesSynth);
            app.WaveAxesSynth.LineWidth = 2;
            linkaxes([app.SgramAxes, app.WaveAxes, app.FoAxes], 'off');
            linkaxes([app.SgramAxes, app.WaveAxes, app.FoAxes, app.WaveAxesSynth], 'x');
            app.PlaysynthButton.Enable = 'on';
            app.SaveParameterButton.Enable = 'on';
            %app.SavesynthButton.Enable = 'on';
            app.YLamp.Visible = 'off';
            app.PLamp.Visible = 'off';
        end

        % Button pushed function: SpectrumButton
        function SpectrumButtonPushed(app, event)
            fs = app.CommonData.samplingFrequency;
            x = app.CommonData.x;
            span = app.CommonData.span;
            f0_parameter = app.CommonData.f0_parameter;
            option_cheaptrick.fft_size = 4096;
            spectrum_parameter = CheapTrick(x(span), fs, f0_parameter, option_cheaptrick);
            app.CommonData.spectrum_parameter = spectrum_parameter;
            app.SgramAxes.Visible = 'on';
            ttSgram = spectrum_parameter.temporal_positions + (span(1)-1)/fs;
            dBSgram = 10*log10(spectrum_parameter.spectrogram);
            dBSgram = dBSgram - max(dBSgram(:));
            app.CommonData.sgramHandle = imagesc(app.SgramAxes, ...
                [ttSgram(1) ttSgram(end)], [0 fs/2]/1000, ...
                max(-80, dBSgram));
            axis(app.SgramAxes, 'xy');
            title(app.SgramAxes, '');
            colormap(app.SgramAxes, "turbo");
            app.CommonData.colorbarHandle = colorbar(app.SgramAxes,"northoutside");
            xlabel(app.SgramAxes, 'time (s)');
            ylabel(app.SgramAxes, 'frequency (kHz)');
            xLim = app.WaveAxes.XLim;
            app.CommonData.SgramAxesToolBar = ...
                axtoolbar(app.SgramAxes,{'pan','zoomin','zoomout','restoreview'});
            disableDefaultInteractivity(app.SgramAxes);
            axis(app.SgramAxes, [xLim(1) xLim(end) [0 fs/2]/1000]);
            linkaxes([app.FoAxes, app.WaveAxes], 'off');
            linkaxes([app.SgramAxes, app.WaveAxes, app.FoAxes], 'x');
            app.SynthesisButton.Enable = 'on';
            app.SLamp.Visible = 'off';
            app.SaveParameterButton.Enable = 'on';
            app.CommonData.spectrum = 'on';
            app.CommonData.synthSound = 'off';
        end

        % Button pushed function: PlaysynthButton
        function PlaysynthButtonPushed(app, event)
            xLim = app.WaveAxes.XLim;
            tty = app.CommonData.tty;
            fs = app.CommonData.samplingFrequency;
            y = app.CommonData.y;
            yTrim = y(tty > xLim(1) & tty < xLim(2));
            if ~isempty(yTrim)
                app.CommonData.audioPlayer = ...
                    audioplayer(yTrim/max(abs(yTrim)), fs, 24,...
                    app.CommonData.deviceID);
                play(app.CommonData.audioPlayer);
            end
        end

        % Button down function: FoAxes
        function FoAxesButtonDown(app, event)
            set(app.UIFigure, "Pointer", 'crosshair')
            app.CommonData.foEdit = 'on';
            app.CommonData.pointHistory = app.FoAxes.CurrentPoint;
            hold(app.FoAxes, "on");
            app.CommonData.hotTrajectory = plot(app.FoAxes, app.CommonData.pointHistory(1), ...
                app.CommonData.pointHistory(2), '-', "LineWidth", 4, "Color", [255 147 0]/255);
            app.CommonData.foEditCount = app.CommonData.foEditCount + 1;
            app.CommonData.foEditData(app.CommonData.foEditCount).handle = app.CommonData.hotTrajectory;
            drawnow;
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            switch app.CommonData.foEdit
                case 'on'
                    app.CommonData.pointHistory = [app.CommonData.pointHistory; ...
                        app.FoAxes.CurrentPoint];
                    app.CommonData.hotTrajectory.XData = ...
                        app.CommonData.pointHistory(:,1);
                    app.CommonData.hotTrajectory.YData = ...
                        app.CommonData.pointHistory(:,2);
                    drawnow;
                case 'vuvOn'
                    tmpPoint = app.WaveAxes.CurrentPoint;
                    %set(app.CommonData.lineHandle, 'XData', tmpPoint(1,1)* [1 1]);
                    app.CommonData.lineHandle.Value = tmpPoint(1,1);%* [1 1];
                    drawnow limitrate
                case 'vuvOff'
                    tmpPoint = app.WaveAxes.CurrentPoint;
                    %set(app.CommonData.lineHandle, 'XData', tmpPoint(1,1)* [1 1]);
                    app.CommonData.lineHandle.Value = tmpPoint(1,1);%* [1 1];
                    drawnow limitrate
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            switch app.CommonData.foEdit
                case 'on'
                    xData = app.CommonData.hotTrajectory.XData;
                    yData = app.CommonData.hotTrajectory.YData;
                    [sortedXdata, sortIdx] = sort(xData);
                    sortedYdata = yData(sortIdx);
                    [sortedXdata, ic2, ~] = unique(sortedXdata);
                    sortedYdata = sortedYdata(ic2);
                    tFrame = app.CommonData.tFrameFo;
                    tFrameTrim = tFrame(tFrame >= sortedXdata(1) ...
                        & tFrame <= sortedXdata(end));
                    f0_edit = exp(interp1(sortedXdata, sortedYdata, ...
                        tFrameTrim, "linear","extrap"));
                    f0_edit(f0_edit <= app.CommonData.option_harvest.f0_floor) = 0;
                    foOriginalTrim = app.CommonData.f0(tFrame >= sortedXdata(1) ...
                        & tFrame <= sortedXdata(end));
                    app.CommonData.f0(tFrame >= sortedXdata(1) ...
                        & tFrame <= sortedXdata(end)) = ...
                        f0_edit;
                    app.CommonData.f0Handle.YData = ...
                        log(app.CommonData.f0(:) .* app.CommonData.viewVUV(:));
                    drawnow;
                    foIdx = 1:length(tFrame);
                    foIdxTrim = foIdx(tFrame >= sortedXdata(1) ...
                        & tFrame <= sortedXdata(end));
                    app.CommonData.foEditData(app.CommonData.foEditCount).editIdx = foIdxTrim;
                    app.CommonData.foEditData(app.CommonData.foEditCount).foOriginal = foOriginalTrim;
                    app.CommonData.foEdit = 'off';
                    %set(app.UIFigure, "Pointer", 'arrow')
                    app.UNDOButton.Enable = 'on';
                    app.RESETButton.Enable = 'on';
                    app.CommonData.aperiodicity = 'off';
                    app.CommonData.spectrum = 'off';
                    app.CommonData.synthSound = 'off';
                    app.SaveParameterButton.Enable = 'off';
                    app.SavesynthButton.Enable = 'off';
                case 'vuvOn'
                    set(app.CommonData.lineHandle, 'linewidth', 0.5);
                    updateVUV(app);
                    app.CommonData.f0Handle.YData = ...
                        log(app.CommonData.f0(:) .* app.CommonData.viewVUV(:));
                    app.CommonData.foEdit = 'off';
                    app.CommonData.aperiodicity = 'off';
                    app.CommonData.spectrum = 'off';
                    app.CommonData.synthSound = 'off';
                    app.SaveParameterButton.Enable = 'off';
                    app.SavesynthButton.Enable = 'off';
                case 'vuvOff'
                    set(app.CommonData.lineHandle, 'linewidth', 0.5);
                    updateVUV(app);
                    app.CommonData.f0Handle.YData = ...
                        log(app.CommonData.f0(:) .* app.CommonData.viewVUV(:));
                    app.CommonData.foEdit = 'off';
                    app.CommonData.aperiodicity = 'off';
                    app.CommonData.spectrum = 'off';
                    app.CommonData.synthSound = 'off';
                    app.SaveParameterButton.Enable = 'off';
                    app.SavesynthButton.Enable = 'off';
            end
            freshStatusUpdate(app);
            %app.SaveParameterButton.Enable = 'off';
            set(app.UIFigure, "Pointer", 'arrow')
        end

        % Button pushed function: UNDOButton
        function UNDOButtonPushed(app, event)
            if app.CommonData.foEditCount > 0
                id = app.CommonData.foEditCount;
                foIdxTrim = app.CommonData.foEditData(id).editIdx;
                app.CommonData.f0(foIdxTrim) = ...
                    app.CommonData.foEditData(id).foOriginal;
                app.CommonData.foEditCount = app.CommonData.foEditCount -1;
                app.CommonData.f0Handle.YData = log(app.CommonData.f0);
                delete(app.CommonData.foEditData(id).handle);
                drawnow;
                freshStatusUpdate(app);
                app.SaveParameterButton.Enable = 'off';
                if app.CommonData.foEditCount == 0
                    app.UNDOButton.Enable = 'off';
                    app.RESETButton.Enable = 'off';
                end
            else
                app.UNDOButton.Enable = 'off';
            end
        end

        % Button pushed function: SaveParameterButton
        function SaveParameterButtonPushed(app, event)
            outFileName = "worldPrm" + datestr(now,30) + "d.mat";
            worldParameter = struct;
            worldParameter.creator = 'worldHandler.mlapp';
            worldParameter.signal = app.CommonData.x;
            worldParameter.fileFullPath = app.fileFullPathTextLabel.Text;
            worldParameter.fileName = app.fileNameTextLabel.Text;
            worldParameter.samplingFrequency = app.CommonData.samplingFrequency;
            worldParameter.span = app.CommonData.span;
            worldParameter.spectrum_parameter = app.CommonData.spectrum_parameter;
            worldParameter.source_parameter = app.CommonData.source_parameter;
            worldParameter.f0_original = app.CommonData.f0_original;
            if isfield(app.CommonData,'outFilePath')
                oldPath = cd(app.CommonData.outFilePath);
            end
            [fileName,filePath] = uiputfile('','save parameter',outFileName);
            if isfield(app.CommonData,'outFilePath')
                cd(oldPath);
            end
            app.CommonData.outFileName = string(fileName);
            app.CommonData.outFilePath = string(filePath);
            %disp(fileName)a
            save(app.CommonData.outFilePath + app.CommonData.outFileName, "worldParameter");
            app.SaveParameterButton.Enable = 'off';
            app.PLamp.Visible = 'off';
            app.SavesynthButton.Enable = 'on';
        end

        % Button pushed function: SavesynthButton
        function SavesynthButtonPushed(app, event)
            outSynthFileName = "worldSynth" + datestr(now,30) + "d.wav";
            fs = app.CommonData.samplingFrequency;
            dlgletter = 'synth file name definition';
            prompt = 'Enter file name';
            dims = [1 45];
            answer = inputdlg(prompt, dlgletter, dims, outSynthFileName);
            app.CommonData.outSynthFileName = answer{1};
            audiowrite(app.CommonData.outFilePath+app.CommonData.outSynthFileName,...
                app.CommonData.y, fs,"BitsPerSample", 24, ...
                "Comment", app.CommonData.outFileName);
            app.SavesynthButton.Enable = 'off';
            app.VLamp.Visible = 'off';
            app.CommonData.synthSound = 'on';
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            if app.CommonData.foEditCount > 0
                for id = app.CommonData.foEditCount:-1:1
                    foIdxTrim = app.CommonData.foEditData(id).editIdx;
                    app.CommonData.f0(foIdxTrim) = ...
                        app.CommonData.foEditData(id).foOriginal;
                    app.CommonData.foEditCount = app.CommonData.foEditCount -1;
                    app.CommonData.f0Handle.YData = log(app.CommonData.f0);
                    delete(app.CommonData.foEditData(id).handle);
                    drawnow;
                    freshStatusUpdate(app);
                end
                app.SaveParameterButton.Enable = 'on';
                app.RESETButton.Enable = 'off';
                app.UNDOButton.Enable = 'off';
            else
                app.UNDOButton.Enable = 'off';
                app.RESETButton.Enable = 'off';
            end
        end

        % Button pushed function: ChangeaudiooutdeviceButton
        function ChangeaudiooutdeviceButtonPushed(app, event)
            fs = app.CommonData.samplingFrequency;
            tt = (1:fs/2)'/fs;
            x = 0.2*sin(2*pi*tt*440);
            info = audiodevinfo;
            T = struct2table(info.output);
            [id, tf] = listdlg("ListString", T.Name, "SelectionMode","single", ...
                "PromptString","Select audio output","ListSize",[250, 150]);
            if tf
                app.CommonData.deviceID = info.output(id).ID;
                app.CommonData.player = audioplayer(x, fs, 24, app.CommonData.deviceID);
                play(app.CommonData.player)
            else
                disp('Audio output devide selection is cancelled.')
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            %key = event.Key;
            UIFigureWindowButtonUp(app, event);
        end

        % Button down function: WaveAxes
        function WaveAxesButtonDown(app, event)
            onsetdHandle = app.CommonData.onsetdHandle;
            offsetdHandle = app.CommonData.offsetdHandle;
            nSegment = length(onsetdHandle);
            idType = 'onset';
            id = 1;
            minimumDist = 100000000000000;
            currentLoc = app.WaveAxes.CurrentPoint;
            for ii = 1:nSegment
                xDataOn = get(onsetdHandle(ii), 'Value');
                xDataOff = get(offsetdHandle(ii), 'Value');
                onDist = abs(xDataOn(1,1) - currentLoc(1,1));
                offDist = abs(xDataOff(1,1) - currentLoc(1,1));
                if  onDist < offDist
                    if onDist < minimumDist
                        minimumDist = onDist;
                        id = ii;
                        idType = 'onset';
                    end
                elseif offDist < minimumDist
                    minimumDist = offDist;
                    id = ii;
                    idType = 'offset';
                end
            end
            if minimumDist < app.CommonData.separationMargin
                set(app.UIFigure, "Pointer", 'left')
                switch idType
                    case 'onset'
                        app.CommonData.lineHandle = onsetdHandle(id);
                        set(app.CommonData.lineHandle, 'linewidth', 3);
                        app.CommonData.foEdit = 'vuvOn';
                    case 'offset'
                        app.CommonData.lineHandle = offsetdHandle(id);
                        set(app.CommonData.lineHandle, 'linewidth', 3);
                        app.CommonData.foEdit = 'vuvOff';
                end
            else
                [~, minIdx] = min(abs(currentLoc(1,1) - app.CommonData.tFrameFo));
                app.CommonData.newSegmentCenterIdx = minIdx;
                switch app.CommonData.viewVUV(minIdx)
                    case 0
                        onLoc = currentLoc(1,1) - 0.005;
                        offLoc = currentLoc(1,1) + 0.005;
                        app.CommonData.newOnHandle = xline(app.WaveAxes, ...
                        onLoc, "Color",[0 1 0], "LineWidth", 0.5, HitTest="off");
                        app.CommonData.newOffHandle = xline(app.WaveAxes, ...
                        offLoc, "Color",[1 0 0], "LineWidth", 0.5, HitTest="off");
                    case 1
                        onLoc = currentLoc(1,1) + 0.005;
                        offLoc = currentLoc(1,1) - 0.005;
                        app.CommonData.newOnHandle = xline(app.WaveAxes, ...
                        onLoc, "Color",[0 1 0], "LineWidth", 0.5, HitTest="off");
                        app.CommonData.newOffHandle = xline(app.WaveAxes, ...
                        offLoc, "Color",[1 0 0], "LineWidth", 0.5, HitTest="off");
                end
                drawnow
                app.CommonData.foEdit = '';
                updateOnOffHandle(app);
                updateVUV(app);
                drawnow
            end
        end

        % Button pushed function: PlayVoicedButton
        function PlayVoicedButtonPushed(app, event)
            xLim = app.WaveAxes.XLim;
            fs = app.CommonData.samplingFrequency;
            x = app.CommonData.x .* app.CommonData.soundVUV;
            nData = length(x);
            xLimSample = max(1, min(nData, 1+round(xLim * fs)));
            span = xLimSample(1):xLimSample(end);
            app.CommonData.audioPlayer = ...
                audioplayer(x(span)/max(abs(x(span))), fs, 24,...
                app.CommonData.deviceID);
            play(app.CommonData.audioPlayer);
        end

        % Button pushed function: PlayUVButton
        function PlayUVButtonPushed(app, event)
            xLim = app.WaveAxes.XLim;
            fs = app.CommonData.samplingFrequency;
            x = app.CommonData.x .* (1-app.CommonData.soundVUV);
            nData = length(x);
            xLimSample = max(1, min(nData, 1+round(xLim * fs)));
            span = xLimSample(1):xLimSample(end);
            app.CommonData.audioPlayer = ...
                audioplayer(x(span)/max(abs(x(span))), fs, 24,...
                app.CommonData.deviceID);
            play(app.CommonData.audioPlayer);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ReadsoundButton = uibutton(app.LeftPanel, 'push');
            app.ReadsoundButton.ButtonPushedFcn = createCallbackFcn(app, @ReadsoundButtonPushed, true);
            app.ReadsoundButton.Text = 'Read sound';
            app.FoextractorButton = uibutton(app.LeftPanel, 'push');
            app.FoextractorButton.ButtonPushedFcn = createCallbackFcn(app, @FoextractorButtonPushed, true);
            app.FoextractorButton.Enable = 'off';
            app.FoextractorButton.Text = 'Fo extractor';
            app.AperiodicityButton = uibutton(app.LeftPanel, 'push');
            app.AperiodicityButton.ButtonPushedFcn = createCallbackFcn(app, @AperiodicityButtonPushed, true);
            app.AperiodicityButton.Enable = 'off';
            app.AperiodicityButton.Text = 'Aperiodicity';
            app.SpectrumButton = uibutton(app.LeftPanel, 'push');
            app.SpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrumButtonPushed, true);
            app.SpectrumButton.Enable = 'off';
            app.SpectrumButton.Text = 'Spectrum';
            app.SynthesisButton = uibutton(app.LeftPanel, 'push');
            app.SynthesisButton.ButtonPushedFcn = createCallbackFcn(app, @SynthesisButtonPushed, true);
            app.SynthesisButton.Enable = 'off';
            app.SynthesisButton.Text = 'Synthesis';
            app.SaveParameterButton = uibutton(app.LeftPanel, 'push');
            app.SaveParameterButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParameterButtonPushed, true);
            app.SaveParameterButton.Enable = 'off';
            app.SaveParameterButton.Text = 'Save Parameter';
            app.PlayoriginalButton = uibutton(app.LeftPanel, 'push');
            app.PlayoriginalButton.ButtonPushedFcn = createCallbackFcn(app, @PlayoriginalButtonPushed, true);
            app.PlayoriginalButton.Enable = 'off';
            app.PlayoriginalButton.Text = 'Play original';
            app.QUITButton = uibutton(app.LeftPanel, 'push');
            app.QUITButton.ButtonPushedFcn = createCallbackFcn(app, @QUITButtonPushed, true);
            app.QUITButton.Text = 'QUIT';
            app.WorldHandlerLabel = uilabel(app.LeftPanel);
            app.WorldHandlerLabel.Text = 'World Handler';
            app.PlaysynthButton = uibutton(app.LeftPanel, 'push');
            app.PlaysynthButton.ButtonPushedFcn = createCallbackFcn(app, @PlaysynthButtonPushed, true);
            app.PlaysynthButton.Enable = 'off';
            app.PlaysynthButton.Text = 'Play synth.';
            app.SavesynthButton = uibutton(app.LeftPanel, 'push');
            app.SavesynthButton.ButtonPushedFcn = createCallbackFcn(app, @SavesynthButtonPushed, true);
            app.SavesynthButton.Enable = 'off';
            app.SavesynthButton.Text = 'Save synth.';
            app.UNDOButton = uibutton(app.LeftPanel, 'push');
            app.UNDOButton.ButtonPushedFcn = createCallbackFcn(app, @UNDOButtonPushed, true);
            app.UNDOButton.Enable = 'off';
            app.UNDOButton.Text = 'UNDO';
            app.ALampLabel = uilabel(app.LeftPanel);
            app.ALampLabel.Visible = 'off';
            app.ALampLabel.Text = 'A';
            app.ALamp = uilamp(app.LeftPanel);
            app.ALamp.Visible = 'off';
            app.SLampLabel = uilabel(app.LeftPanel);
            app.SLampLabel.Visible = 'off';
            app.SLampLabel.Text = 'S';
            app.SLamp = uilamp(app.LeftPanel);
            app.SLamp.Visible = 'off';
            app.YLampLabel = uilabel(app.LeftPanel);
            app.YLampLabel.Visible = 'off';
            app.YLampLabel.Text = 'Y';
            app.YLamp = uilamp(app.LeftPanel);
            app.YLamp.Visible = 'off';
            app.PLampLabel = uilabel(app.LeftPanel);
            app.PLampLabel.Visible = 'off';
            app.PLampLabel.Text = 'P';
            app.PLamp = uilamp(app.LeftPanel);
            app.PLamp.Visible = 'off';
            app.VLampLabel = uilabel(app.LeftPanel);
            app.VLampLabel.Visible = 'off';
            app.VLampLabel.Text = 'V';
            app.VLamp = uilamp(app.LeftPanel);
            app.VLamp.Visible = 'off';
            app.RESETButton = uibutton(app.LeftPanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Enable = 'off';
            app.RESETButton.Text = 'RESET';
            app.ChangeaudiooutdeviceButton = uibutton(app.LeftPanel, 'push');
            app.ChangeaudiooutdeviceButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeaudiooutdeviceButtonPushed, true);
            app.ChangeaudiooutdeviceButton.Text = 'Change audio out device';
            app.PlayUVButton = uibutton(app.LeftPanel, 'push');
            app.PlayUVButton.ButtonPushedFcn = createCallbackFcn(app, @PlayUVButtonPushed, true);
            app.PlayUVButton.Enable = 'off';
            app.PlayUVButton.Text = 'Play UV';
            app.PlayVoicedButton = uibutton(app.LeftPanel, 'push');
            app.PlayVoicedButton.ButtonPushedFcn = createCallbackFcn(app, @PlayVoicedButtonPushed, true);
            app.PlayVoicedButton.Enable = 'off';
            app.PlayVoicedButton.Text = 'Play Voiced';
            app.RightPanel = uipanel(app.GridLayout);
            app.FoAxes = uiaxes(app.RightPanel);
            title(app.FoAxes, 'Title')
            xlabel(app.FoAxes, 'X')
            ylabel(app.FoAxes, 'Y')
            zlabel(app.FoAxes, 'Z')
            app.FoAxes.Visible = 'off';
            app.FoAxes.ButtonDownFcn = createCallbackFcn(app, @FoAxesButtonDown, true);
            app.WaveAxes = uiaxes(app.RightPanel);
            title(app.WaveAxes, 'Title')
            xlabel(app.WaveAxes, 'X')
            ylabel(app.WaveAxes, 'Y')
            zlabel(app.WaveAxes, 'Z')
            app.WaveAxes.Visible = 'off';
            app.WaveAxes.ButtonDownFcn = createCallbackFcn(app, @WaveAxesButtonDown, true);
            app.SgramAxes = uiaxes(app.RightPanel);
            title(app.SgramAxes, 'Title')
            xlabel(app.SgramAxes, 'X')
            ylabel(app.SgramAxes, 'Y')
            zlabel(app.SgramAxes, 'Z')
            app.SgramAxes.Visible = 'off';
            app.WaveAxesSynth = uiaxes(app.RightPanel);
            title(app.WaveAxesSynth, 'Title')
            xlabel(app.WaveAxesSynth, 'X')
            ylabel(app.WaveAxesSynth, 'Y')
            zlabel(app.WaveAxesSynth, 'Z')
            app.WaveAxesSynth.Visible = 'off';
            app.fileFullPathLabel = uilabel(app.RightPanel);
            app.fileFullPathLabel.Text = 'fileFullPath:';
            app.FileNameLabel = uilabel(app.RightPanel);
            app.FileNameLabel.Text = 'FileName:';
            app.fileFullPathTextLabel = uilabel(app.RightPanel);
            app.fileFullPathTextLabel.Visible = 'off';
            app.fileFullPathTextLabel.Text = 'fileFullPathText';
            app.fileNameTextLabel = uilabel(app.RightPanel);
            app.fileNameTextLabel.Visible = 'off';
            app.fileNameTextLabel.Text = 'fileNameText';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = worldHandler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end